classdef ship_ice < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ShipinitializationPanel        matlab.ui.container.Panel
        DimensionsPanel                matlab.ui.container.Panel
        DraughtSpinnerLabel            matlab.ui.control.Label
        DraughtSpinner                 matlab.ui.control.Spinner
        WLangleSpinnerLabel            matlab.ui.control.Label
        WLangleSpinner                 matlab.ui.control.Spinner
        StemangleSpinnerLabel          matlab.ui.control.Label
        StemangleSpinner               matlab.ui.control.Spinner
        z_CoGSpinnerLabel              matlab.ui.control.Label
        z_CoGSpinner                   matlab.ui.control.Spinner
        GMSpinnerLabel                 matlab.ui.control.Label
        GMSpinner                      matlab.ui.control.Spinner
        MassSpinnerLabel               matlab.ui.control.Label
        MassSpinner                    matlab.ui.control.Spinner
        IxxSpinnerLabel                matlab.ui.control.Label
        IxxSpinner                     matlab.ui.control.Spinner
        IzzSpinnerLabel                matlab.ui.control.Label
        IzzSpinner                     matlab.ui.control.Spinner
        PropulsionPanel                matlab.ui.container.Panel
        FullpowerSpinnerLabel          matlab.ui.control.Label
        FullpowerSpinner               matlab.ui.control.Spinner
        PropellerdiameterSpinnerLabel  matlab.ui.control.Label
        PropellerdiameterSpinner       matlab.ui.control.Spinner
        MaxpitchdiameterSpinnerLabel   matlab.ui.control.Label
        MaxpitchdiameterSpinner        matlab.ui.control.Spinner
        CPPCheckBox                    matlab.ui.control.CheckBox
        FPPCheckBox                    matlab.ui.control.CheckBox
        PropellertypeLabel             matlab.ui.control.Label
        MaxOWspeedSpinnerLabel         matlab.ui.control.Label
        MaxOWspeedSpinner              matlab.ui.control.Spinner
        ProppositionxSpinnerLabel      matlab.ui.control.Label
        ProppositionxSpinner           matlab.ui.control.Spinner
        ProppositionzSpinnerLabel      matlab.ui.control.Label
        ProppositionzSpinner           matlab.ui.control.Spinner
        CheckBox                       matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        NoofpropellerLabel             matlab.ui.control.Label
        RudderPanel                    matlab.ui.container.Panel
        RudderheightSpinnerLabel       matlab.ui.control.Label
        RudderheightSpinner            matlab.ui.control.Spinner
        RudderareaSpinnerLabel         matlab.ui.control.Label
        RudderareaSpinner              matlab.ui.control.Spinner
        PositionxSpinnerLabel          matlab.ui.control.Label
        PositionxSpinner               matlab.ui.control.Spinner
        PositionzSpinnerLabel          matlab.ui.control.Label
        PositionzSpinner               matlab.ui.control.Spinner
        IceinitializationPanel         matlab.ui.container.Panel
        LevelicedimensionPanel         matlab.ui.container.Panel
        xSpinnerLabel                  matlab.ui.control.Label
        xSpinner                       matlab.ui.control.Spinner
        ySpinnerLabel                  matlab.ui.control.Label
        ySpinner                       matlab.ui.control.Spinner
        ThicknessPanel                 matlab.ui.container.Panel
        MeanSpinnerLabel               matlab.ui.control.Label
        MeanSpinner                    matlab.ui.control.Spinner
        StddeviationSpinnerLabel       matlab.ui.control.Label
        StddeviationSpinner            matlab.ui.control.Spinner
        StrengthpropertyPaPanel        matlab.ui.container.Panel
        sig_cSpinnerLabel              matlab.ui.control.Label
        sig_cSpinner                   matlab.ui.control.Spinner
        sig_fSpinnerLabel              matlab.ui.control.Label
        sig_fSpinner                   matlab.ui.control.Spinner
        ESpinnerLabel                  matlab.ui.control.Label
        ESpinner                       matlab.ui.control.Spinner
        stdSpinnerLabel                matlab.ui.control.Label
        stdSpinner                     matlab.ui.control.Spinner
        stdSpinner_2Label              matlab.ui.control.Label
        stdSpinner_2                   matlab.ui.control.Spinner
        ImplementButton                matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        SimulationsetupPanel           matlab.ui.container.Panel
        ScenariodetailsPanel           matlab.ui.container.Panel
        DistanceSpinnerLabel           matlab.ui.control.Label
        DistanceSpinner                matlab.ui.control.Spinner
        InitialspeedSpinnerLabel       matlab.ui.control.Label
        InitialspeedSpinner            matlab.ui.control.Spinner
        RudderangleSpinnerLabel        matlab.ui.control.Label
        RudderangleSpinner             matlab.ui.control.Spinner
        PowerSpinnerLabel              matlab.ui.control.Label
        PowerSpinner                   matlab.ui.control.Spinner
        ProppitchSpinnerLabel          matlab.ui.control.Label
        ProppitchSpinner               matlab.ui.control.Spinner
        UIAxes2                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImplementButton
        function ImplementButtonPushed(app, event)
            % Use this script to define ship and ice parameters and simulation scenario
            
            %% Ship parameters
            load Agulhashull22112019.mat % hull line and angles
            
            T = app.StemangleSpinner.Value; % Draught
            
            alpha = app.WLangleSpinner.Value; % Waterline angle at stem, deg
            phib = app.StemangleSpinner.Value; % Stem angle
            
            zCOG = app.z_CoGSpinner.Value; % Location of CoG
            
            m = app.MassSpinner.Value;
            Ixx = app.IxxSpinner.Value;
            Izz = app.IzzSpinner.Value;
            
            [ship] = hull_teho(x_f, y_f, x_phi, phiIn, 1, T, zCOG, m, Ixx, Izz, alpha, phib);
            ship.name = 'Ship'; % Name of the ship
            ship.GMroll = app.GMSpinner.Value;
            
            % Propulsion
            ship.dprop = app.PropellerdiameterSpinner.Value; % propeller diameter, m
            ship.fullpower = app.FullpowerSpinner.Value; % Full power, kW
            ship.fullpitch = app.MaxpitchdiameterSpinner.Value; % Max propeller pitch to diameter ratio (p/D)
            ship.vow = app.MaxOWspeedSpinner.Value;
            
            % A reference point of ship power, propeller p/D and speed
            ship.refpower = app.FullpowerSpinner.Value; % Reference power
            ship.refpitch = app.MaxpitchdiameterSpinner.Value; % reference p/D for bollard pull calculation
            ship.refvow = app.MaxOWspeedSpinner.Value; % Reference open water speed
            
            % ship rudder
            b = app.RudderheightSpinner.Value; % Rudder height
            ship.Ar = app.RudderareaSpinner.Value; % Rudder area, m2
            ship.Rasp = b^2/ship.Ar; % Aspect ratio
            ship.chord = b/ship.Rasp;% Chord length, m
            ship.rudpos(1) = app.PositionxSpinner.Value;
            ship.rudpos(2) = app.PositionzSpinner.Value; % Rudder coordinate
            ship.Kr = 0.5+0.5/(1+0.15/(2.4/ship.dprop));
            
            % Round ship coordinate
            ship.x = round(ship.x,4);
            ship.y = round(ship.y,4);
            
            %% Ice parameters
            h = app.MeanSpinner.Value; % mean ice thickness, m
            h_std = app.StddeviationSpinner.Value; % std of ice thickness, m
            
            sigc = app.sig_cSpinner.Value; % Mean compressive strength, Pa
            sigc_std = app.stdSpinner.Value; % Std of compressive strength, Pa
            sigf = app.sig_fSpinner.Value; % Mean flexural strength, Pa
            sigf_std = app.stdSpinner_2.Value; % Std of flexural strength, Pa
            E = app.ESpinner.Value; % Young's modulus, Pa
            
            %% Simulation scenario
            rdag = app.RudderangleSpinner.Value; % Rudder angle, deg
            pow = app.PowerSpinner.Value; % Engine power, kW
            pp = app.ProppitchSpinner.Value; % propeller pitch, % of max
            
            dis = app.DistanceSpinner.Value; % Distance to simulate in ship initial heading direction, m
            vinit = app.InitialspeedSpinner.Value; % Initial speed, m/s
            ship.vcog = [vinit 0 0 0]';
            
            operationmode = 1; % 1 for self-propulsion, 2 for towing
            icecondition = 1; % 1 for level ice, 2 for channel
            
            % Compressive ice channel, not functioning in the current version
            v_chan = [0,0]; 
            vl = v_chan(1);
            vr = v_chan(2);
            
            Thrust = []; % Leave as [] if not known
            power = pow*ones(1,dis);
            prop_pitch = ones(1,dis)*pp*ship.fullpitch/100;
            
            %% Ice field initialization
            
            % Ice field thickness in 1m resolution
            iceThick = h+h_std*randn(1,dis);
            % eliminate abnormally large values
            for i = 1:dis
                while iceThick(i)<0.75*h || iceThick(i)>1.25*h
                    iceThick(i) = h+h_std*randn(1);
                end
            end
            ridgeThick = zeros(length(iceThick),1);%Ice ridge thickness
            
            % Initiate ice field
            m = app.ySpinner.Value; % Dimension of ice sheet in y-direction
            ship.rcog = [-100 m-200 0 0]';
            n = app.xSpinner.Value; % Dimension of ice sheet in x-direction
            n = (length(iceThick)==1)*n+(length(iceThick)>1)*length(iceThick);%iceThick is scalar when mean value is used, vector when coordinates are used
            
            d = 15; % Only useful in compressive channel
            if icecondition == 1
                [iceField] = icefield(n,m, 0, iceThick,zeros(size(iceThick)),1,sigc,sigc_std,sigf,sigf_std,E); 
            elseif icecondition == 2
                [iceField] = channelfield(n,m, d, iceThick, 1); 
            end
            
            %% Run
            masterScript_v1(ship,iceField,h,dis,operationmode,icecondition,v_chan,1,power,prop_pitch,Thrust,vinit,rdag,app.UIAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ShipinitializationPanel = uipanel(app.UIFigure);
            app.ShipinitializationPanel.Title = 'Ship initialization';
            app.DimensionsPanel = uipanel(app.ShipinitializationPanel);
            app.DimensionsPanel.Title = 'Dimensions';
            app.DraughtSpinnerLabel = uilabel(app.DimensionsPanel);
            app.DraughtSpinnerLabel.Text = 'Draught';
            app.DraughtSpinner = uispinner(app.DimensionsPanel);
            app.DraughtSpinner.Limits = [0 Inf];
            app.DraughtSpinner.Value = 7;
            app.WLangleSpinnerLabel = uilabel(app.DimensionsPanel);
            app.WLangleSpinnerLabel.Text = 'WL angle';
            app.WLangleSpinner = uispinner(app.DimensionsPanel);
            app.WLangleSpinner.Limits = [0 Inf];
            app.WLangleSpinner.Value = 33;
            app.StemangleSpinnerLabel = uilabel(app.DimensionsPanel);
            app.StemangleSpinnerLabel.Text = 'Stem angle';
            app.StemangleSpinner = uispinner(app.DimensionsPanel);
            app.StemangleSpinner.Limits = [0 Inf];
            app.StemangleSpinner.Value = 20;
            app.z_CoGSpinnerLabel = uilabel(app.DimensionsPanel);
            app.z_CoGSpinnerLabel.Text = 'z_CoG';
            app.z_CoGSpinner = uispinner(app.DimensionsPanel);
            app.z_CoGSpinner.Value = 8;
            app.GMSpinnerLabel = uilabel(app.DimensionsPanel);
            app.GMSpinnerLabel.Text = 'GM';
            app.GMSpinner = uispinner(app.DimensionsPanel);
            app.GMSpinner.Value = 1;
            app.MassSpinnerLabel = uilabel(app.DimensionsPanel);
            app.MassSpinnerLabel.Text = 'Mass';
            app.MassSpinner = uispinner(app.DimensionsPanel);
            app.MassSpinner.Value = 10000;
            app.IxxSpinnerLabel = uilabel(app.DimensionsPanel);
            app.IxxSpinnerLabel.Text = 'Ixx';
            app.IxxSpinner = uispinner(app.DimensionsPanel);
            app.IxxSpinner.Value = 10000000;
            app.IzzSpinnerLabel = uilabel(app.DimensionsPanel);
            app.IzzSpinnerLabel.Text = 'Izz';
            app.IzzSpinner = uispinner(app.DimensionsPanel);
            app.IzzSpinner.Value = 10000000000;
            app.PropulsionPanel = uipanel(app.ShipinitializationPanel);
            app.PropulsionPanel.Title = 'Propulsion';
            app.FullpowerSpinnerLabel = uilabel(app.PropulsionPanel);
            app.FullpowerSpinnerLabel.Text = 'Full power';
            app.FullpowerSpinner = uispinner(app.PropulsionPanel);
            app.FullpowerSpinner.Limits = [0 Inf];
            app.FullpowerSpinner.Value = 9000;
            app.PropellerdiameterSpinnerLabel = uilabel(app.PropulsionPanel);
            app.PropellerdiameterSpinnerLabel.Text = 'Propeller diameter';
            app.PropellerdiameterSpinner = uispinner(app.PropulsionPanel);
            app.PropellerdiameterSpinner.Limits = [0 Inf];
            app.PropellerdiameterSpinner.Value = 4.5;
            app.MaxpitchdiameterSpinnerLabel = uilabel(app.PropulsionPanel);
            app.MaxpitchdiameterSpinnerLabel.Text = 'Max pitch/diameter';
            app.MaxpitchdiameterSpinner = uispinner(app.PropulsionPanel);
            app.MaxpitchdiameterSpinner.Limits = [0 Inf];
            app.MaxpitchdiameterSpinner.Value = 1.25;
            app.CPPCheckBox = uicheckbox(app.PropulsionPanel);
            app.CPPCheckBox.Text = 'CPP';
            app.FPPCheckBox = uicheckbox(app.PropulsionPanel);
            app.FPPCheckBox.Text = 'FPP';
            app.PropellertypeLabel = uilabel(app.PropulsionPanel);
            app.PropellertypeLabel.Text = 'Propeller type';
            app.MaxOWspeedSpinnerLabel = uilabel(app.PropulsionPanel);
            app.MaxOWspeedSpinnerLabel.Text = 'Max OW speed';
            app.MaxOWspeedSpinner = uispinner(app.PropulsionPanel);
            app.MaxOWspeedSpinner.Value = 9;
            app.ProppositionxSpinnerLabel = uilabel(app.PropulsionPanel);
            app.ProppositionxSpinnerLabel.Text = 'Prop position, x';
            app.ProppositionxSpinner = uispinner(app.PropulsionPanel);
            app.ProppositionxSpinner.Value = -50;
            app.ProppositionzSpinnerLabel = uilabel(app.PropulsionPanel);
            app.ProppositionzSpinnerLabel.Text = 'Prop position, z';
            app.ProppositionzSpinner = uispinner(app.PropulsionPanel);
            app.ProppositionzSpinner.Value = -4;
            app.CheckBox = uicheckbox(app.PropulsionPanel);
            app.CheckBox.Text = '1';
            app.CheckBox_2 = uicheckbox(app.PropulsionPanel);
            app.CheckBox_2.Text = '2';
            app.NoofpropellerLabel = uilabel(app.PropulsionPanel);
            app.NoofpropellerLabel.Text = 'No. of propeller';
            app.RudderPanel = uipanel(app.ShipinitializationPanel);
            app.RudderPanel.Title = 'Rudder';
            app.RudderheightSpinnerLabel = uilabel(app.RudderPanel);
            app.RudderheightSpinnerLabel.Text = 'Rudder height';
            app.RudderheightSpinner = uispinner(app.RudderPanel);
            app.RudderheightSpinner.Value = 4.5;
            app.RudderareaSpinnerLabel = uilabel(app.RudderPanel);
            app.RudderareaSpinnerLabel.Text = 'Rudder area';
            app.RudderareaSpinner = uispinner(app.RudderPanel);
            app.RudderareaSpinner.Value = 10;
            app.PositionxSpinnerLabel = uilabel(app.RudderPanel);
            app.PositionxSpinnerLabel.Text = 'Position, x';
            app.PositionxSpinner = uispinner(app.RudderPanel);
            app.PositionxSpinner.Value = -50;
            app.PositionzSpinnerLabel = uilabel(app.RudderPanel);
            app.PositionzSpinnerLabel.Text = 'Position, z';
            app.PositionzSpinner = uispinner(app.RudderPanel);
            app.PositionzSpinner.Value = -4;
            app.IceinitializationPanel = uipanel(app.UIFigure);
            app.IceinitializationPanel.Title = 'Ice initialization';
            app.LevelicedimensionPanel = uipanel(app.IceinitializationPanel);
            app.LevelicedimensionPanel.Title = 'Level ice dimension';
            app.xSpinnerLabel = uilabel(app.LevelicedimensionPanel);
            app.xSpinnerLabel.Text = 'x';
            app.xSpinner = uispinner(app.LevelicedimensionPanel);
            app.xSpinner.Limits = [0 Inf];
            app.xSpinner.Value = 2000;
            app.ySpinnerLabel = uilabel(app.LevelicedimensionPanel);
            app.ySpinnerLabel.Text = 'y';
            app.ySpinner = uispinner(app.LevelicedimensionPanel);
            app.ySpinner.Limits = [0 Inf];
            app.ySpinner.Value = 2000;
            app.ThicknessPanel = uipanel(app.IceinitializationPanel);
            app.ThicknessPanel.Title = 'Thickness';
            app.MeanSpinnerLabel = uilabel(app.ThicknessPanel);
            app.MeanSpinnerLabel.Text = 'Mean';
            app.MeanSpinner = uispinner(app.ThicknessPanel);
            app.MeanSpinner.Limits = [0 Inf];
            app.MeanSpinner.Value = 0.3;
            app.StddeviationSpinnerLabel = uilabel(app.ThicknessPanel);
            app.StddeviationSpinnerLabel.Text = 'Std. deviation';
            app.StddeviationSpinner = uispinner(app.ThicknessPanel);
            app.StddeviationSpinner.Limits = [0 Inf];
            app.StrengthpropertyPaPanel = uipanel(app.IceinitializationPanel);
            app.StrengthpropertyPaPanel.Title = 'Strength property (Pa)';
            app.sig_cSpinnerLabel = uilabel(app.StrengthpropertyPaPanel);
            app.sig_cSpinnerLabel.Text = 'sig_c';
            app.sig_cSpinner = uispinner(app.StrengthpropertyPaPanel);
            app.sig_cSpinner.Limits = [0 Inf];
            app.sig_cSpinner.Value = 2000000;
            app.sig_fSpinnerLabel = uilabel(app.StrengthpropertyPaPanel);
            app.sig_fSpinnerLabel.Text = 'sig_f';
            app.sig_fSpinner = uispinner(app.StrengthpropertyPaPanel);
            app.sig_fSpinner.Limits = [0 Inf];
            app.sig_fSpinner.Value = 500000;
            app.ESpinnerLabel = uilabel(app.StrengthpropertyPaPanel);
            app.ESpinnerLabel.Text = 'E';
            app.ESpinner = uispinner(app.StrengthpropertyPaPanel);
            app.ESpinner.Limits = [0 Inf];
            app.ESpinner.Value = 5000000000;
            app.stdSpinnerLabel = uilabel(app.StrengthpropertyPaPanel);
            app.stdSpinnerLabel.Text = 'std';
            app.stdSpinner = uispinner(app.StrengthpropertyPaPanel);
            app.stdSpinner.Value = 400000;
            app.stdSpinner_2Label = uilabel(app.StrengthpropertyPaPanel);
            app.stdSpinner_2Label.Text = 'std';
            app.stdSpinner_2 = uispinner(app.StrengthpropertyPaPanel);
            app.stdSpinner_2.Value = 100000;
            app.ImplementButton = uibutton(app.UIFigure, 'push');
            app.ImplementButton.ButtonPushedFcn = createCallbackFcn(app, @ImplementButtonPushed, true);
            app.ImplementButton.Text = 'Implement';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory monitor')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SimulationsetupPanel = uipanel(app.UIFigure);
            app.SimulationsetupPanel.Title = 'Simulation setup';
            app.ScenariodetailsPanel = uipanel(app.SimulationsetupPanel);
            app.ScenariodetailsPanel.Title = 'Scenario details';
            app.DistanceSpinnerLabel = uilabel(app.ScenariodetailsPanel);
            app.DistanceSpinnerLabel.Text = 'Distance';
            app.DistanceSpinner = uispinner(app.ScenariodetailsPanel);
            app.DistanceSpinner.Value = 2000;
            app.InitialspeedSpinnerLabel = uilabel(app.ScenariodetailsPanel);
            app.InitialspeedSpinnerLabel.Text = 'Initial speed';
            app.InitialspeedSpinner = uispinner(app.ScenariodetailsPanel);
            app.InitialspeedSpinner.Limits = [0 Inf];
            app.InitialspeedSpinner.Value = 5;
            app.RudderangleSpinnerLabel = uilabel(app.ScenariodetailsPanel);
            app.RudderangleSpinnerLabel.Text = 'Rudder angle';
            app.RudderangleSpinner = uispinner(app.ScenariodetailsPanel);
            app.PowerSpinnerLabel = uilabel(app.ScenariodetailsPanel);
            app.PowerSpinnerLabel.Text = 'Power';
            app.PowerSpinner = uispinner(app.ScenariodetailsPanel);
            app.PowerSpinner.Value = 5000;
            app.ProppitchSpinnerLabel = uilabel(app.ScenariodetailsPanel);
            app.ProppitchSpinnerLabel.Text = 'Prop. pitch (%)';
            app.ProppitchSpinner = uispinner(app.ScenariodetailsPanel);
            app.ProppitchSpinner.Value = 50;
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ship_ice

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end